classdef ffauxivsolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FauxHollowsSolverUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Erase_Label                matlab.ui.control.Label
        Miss_Label                 matlab.ui.control.Label
        Fox_Label                  matlab.ui.control.Label
        Box_Label                  matlab.ui.control.Label
        Swords_Label               matlab.ui.control.Label
        Blocked_Label              matlab.ui.control.Label
        Undo_Button                matlab.ui.control.Button
        Gift_Button                matlab.ui.control.StateButton
        Reset_Button               matlab.ui.control.Button
        Name_TextArea              matlab.ui.control.TextArea
        NameTextAreaLabel          matlab.ui.control.Label
        Tell_Button                matlab.ui.control.StateButton
        Erase_Button               matlab.ui.control.StateButton
        Fox_Button                 matlab.ui.control.StateButton
        Box_Button                 matlab.ui.control.StateButton
        Miss_Button                matlab.ui.control.StateButton
        Swords_Button              matlab.ui.control.StateButton
        Blocked_Button             matlab.ui.control.StateButton
        Button_36                  matlab.ui.control.Button
        Button_35                  matlab.ui.control.Button
        Button_34                  matlab.ui.control.Button
        Button_33                  matlab.ui.control.Button
        Button_32                  matlab.ui.control.Button
        Button_31                  matlab.ui.control.Button
        Button_30                  matlab.ui.control.Button
        Button_29                  matlab.ui.control.Button
        Button_28                  matlab.ui.control.Button
        Button_27                  matlab.ui.control.Button
        Button_26                  matlab.ui.control.Button
        Button_25                  matlab.ui.control.Button
        Button_24                  matlab.ui.control.Button
        Button_23                  matlab.ui.control.Button
        Button_22                  matlab.ui.control.Button
        Button_21                  matlab.ui.control.Button
        Button_20                  matlab.ui.control.Button
        Button_19                  matlab.ui.control.Button
        Button_18                  matlab.ui.control.Button
        Button_17                  matlab.ui.control.Button
        Button_16                  matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        Button_14                  matlab.ui.control.Button
        Button_13                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        Button_11                  matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        Grid_ContextMenu           matlab.ui.container.ContextMenu
        Blocked_Menu               matlab.ui.container.Menu
        Swords_Menu                matlab.ui.container.Menu
        Box_Menu                   matlab.ui.container.Menu
        Fox_Menu                   matlab.ui.container.Menu
        Miss_Menu                  matlab.ui.container.Menu
        Erase_Menu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        brush = 0
        lastBrush = 0;
        lastPatternBrush = 0;
        pattern = nan(6)
        lastPattern = nan(6)
    end
    
    methods (Access = private)
        
        function buttons = patternButtons(app)
            buttons = [app.Button, ...
                app.Button_7, ...
                app.Button_13, ...
                app.Button_19, ...
                app.Button_25, ...
                app.Button_31, ...
                app.Button_2, ...
                app.Button_8, ...
                app.Button_14, ...
                app.Button_20, ...
                app.Button_26, ...
                app.Button_32, ...
                app.Button_3, ...
                app.Button_9, ...
                app.Button_15, ...
                app.Button_21, ...
                app.Button_27, ...
                app.Button_33, ...
                app.Button_4, ...
                app.Button_10, ...
                app.Button_16, ...
                app.Button_22, ...
                app.Button_28, ...
                app.Button_34, ...
                app.Button_5, ...
                app.Button_11, ...
                app.Button_17, ...
                app.Button_23, ...
                app.Button_29, ...
                app.Button_35, ...
                app.Button_6, ...
                app.Button_12, ...
                app.Button_18, ...
                app.Button_24, ...
                app.Button_30, ...
                app.Button_36];
        end
        
        function resetBrushButtons(app, button)
            arguments
                app (1,1)
                button = []
            end
            if isempty(button) || button~=app.Blocked_Button
                app.Blocked_Button.Value = false;
                app.Blocked_Label.Visible = false;
            end
            if isempty(button) || button~=app.Swords_Button
                app.Swords_Button.Value = false;
                app.Swords_Label.Visible = false;
            end
            if isempty(button) || button~=app.Box_Button
                app.Box_Button.Value = false;
                app.Box_Label.Visible = false;
            end
            if isempty(button) || button~=app.Fox_Button
                app.Fox_Button.Value = false;
                app.Fox_Label.Visible = false;
            end
            if isempty(button) || button~=app.Miss_Button
                app.Miss_Button.Value = false;
                app.Miss_Label.Visible = false;
            end
            if isempty(button) || button~=app.Erase_Button
                app.Erase_Button.Value = false;
                app.Erase_Label.Visible = false;
            end
        end
        
        function recalculate(app)
            load patterns.mat patterns names chances metadata

            possiblePatternFilter = all(app.pattern == patterns | isnan(app.pattern), [1 2]);
            possiblePatterns = patterns(:, :, possiblePatternFilter);
            possibleNames = names(:, :, possiblePatternFilter);
            possibleChances = chances(:, :, possiblePatternFilter);
            possibleChances = possibleChances/sum(possibleChances);
            possibleMetadata = metadata(:, :, possiblePatternFilter);

            if isempty(possibleNames)
                selectErase(app);
                shannon = zeros(6);
                pHit = zeros(6);
                if ~isempty(app.Name_TextArea.Value{1}) && app.Name_TextArea.Value{1}(1) == 'C'
                    app.Name_TextArea.Value = "Please send in-game screenshot";
                else
                    app.Name_TextArea.Value = "Double check pattern";
                end
            else
                if ~isnan(app.brush)
                    forcedPatternLocations = find(all(possiblePatterns(:, :, 1) == possiblePatterns, 3));
                    app.pattern(forcedPatternLocations) = possiblePatterns(forcedPatternLocations);
                end
                forcedName = extractBefore(possibleNames{1}, 1+sum(all(possibleNames{1}' == reshape([possibleNames{:}], 5, []), 2)));
                app.Name_TextArea.Value = forcedName;

                metadataTypes = unique(metadata);
                p = zeros(6, 6, length(metadataTypes));
                for iM = 1:length(metadataTypes)
                    p(:, :, iM) = sum((possibleMetadata == metadataTypes(iM)).*possibleChances, 3);
                end
                shannon = -sum(p.*log2(p).*(p>0), 3, "omitnan");
                pHit = sum((possiblePatterns > 1).*possibleChances, 3);
                overallScore = shannon+log2(pHit.*isnan(app.pattern));
                overallSquare = find(max(overallScore.*isnan(app.pattern), [], 'all') == overallScore);
            end

            if all(isnan(app.pattern), [1 2]) && isnan(app.brush)
                selectBlocked(app);
            end

            if sum(app.pattern == 0, [1 2]) == 5 && app.brush == 0
                selectSwords(app);
            end

            if sum(app.pattern == 3, [1 2]) == 6 && app.brush == 3
                selectBox(app);
            end

            if sum(app.pattern == 2, [1 2]) == 4 && app.brush == 2
                selectFox(app);
            end

            if isnan(app.brush) || app.brush == 0
                brushSquare = [];
                overallSquare = [];
                if isnan(app.brush)
                    pBrush = nan(6);
                else
                    pBrush = sum((possiblePatterns == app.brush).*possibleChances, 3);
                end
            elseif app.brush == 1 && any(possiblePatterns==app.lastBrush & isnan(app.pattern), 'all')
                pBrush = sum((possiblePatterns == app.lastBrush).*possibleChances, 3);
                brushScore = shannon+log2(pBrush.*isnan(app.pattern));
                brushSquare = find(brushScore==max(brushScore.*any(possiblePatterns==app.lastBrush,3), [], 'all'));
                pBrush = sum((possiblePatterns == app.brush).*possibleChances, 3);
            else
                pBrush = sum((possiblePatterns == app.brush).*possibleChances, 3);
                if app.brush == 1
                    brushSquare = [];
                else
                    brushScore = shannon+log2(pBrush.*isnan(app.pattern));
                    brushSquare = find(brushScore==max(brushScore.*any(possiblePatterns==app.brush,3), [], 'all'));
                end
            end

            if ~isempty(brushSquare) && any(overallSquare == brushSquare', 'all')
                overallSquare = [];
            end
            
            potentialSquares = find(any(isnan(app.pattern) & possiblePatterns == app.brush, 3));

            buttons = app.patternButtons();
            for iB = 1:36
                if isnan(app.pattern(iB))
                    if any(iB == potentialSquares)
                        switch app.brush
                            case 0
                                buttons(iB).BackgroundColor = [109 96 89]/255;
                            case 1
                                buttons(iB).BackgroundColor = [67 56 49]/255;
                            case 2
                                if app.Gift_Button.Value
                                    buttons(iB).BackgroundColor = [72 91 71]/255;
                                else
                                    buttons(iB).BackgroundColor = [96 78 98]/255;
                                end
                            case 3
                                buttons(iB).BackgroundColor = [63 89 104]/255;
                            case 4
                                buttons(iB).BackgroundColor = [127 90 67]/255;
                        end
                    else
                        buttons(iB).BackgroundColor = [67 56 49]/255;
                    end
                    if any(iB == brushSquare)
                        buttons(iB).BackgroundColor = [0 0 0];
                        buttons(iB).Text = "❔";
                        buttons(iB).FontColor = [1 1 1];
                    elseif any(iB == overallSquare)
                        buttons(iB).BackgroundColor = [1 1 1];
                        buttons(iB).Text = "❔";
                        buttons(iB).FontColor = [0 0 0];
                    else
                        buttons(iB).Text = "❓";
                        buttons(iB).FontColor = [0 0 0];
                    end
                else
                    buttons(iB).FontColor = [0 0 0];
                    switch app.pattern(iB)
                        case 0
                            buttons(iB).BackgroundColor = [154 140 132]/255;
                            buttons(iB).Text = "🚫";
                        case 1
                            buttons(iB).BackgroundColor = [250 235 226]/255;
                            buttons(iB).Text = "😐";
                        case 2
                            if app.Gift_Button.Value
                                buttons(iB).BackgroundColor = [74 167 71]/255;
                                buttons(iB).Text = "📦";
                            else
                                buttons(iB).BackgroundColor = [188 105 199]/255;
                                buttons(iB).Text = "🎁";
                            end
                        case 3
                            buttons(iB).BackgroundColor = [25 172 231]/255;
                            buttons(iB).Text = "⚔️";
                        case 4
                            buttons(iB).BackgroundColor = [233 152 99]/255;
                            buttons(iB).Text = "🦊";
                    end
                end
                buttons(iB).Text = [buttons(iB).Text sprintf("H: %.1f", shannon(iB)) sprintf("p(h): %.0f", pHit(iB)*100) sprintf("p(br): %.0f", pBrush(iB)*100)];
            end
        end
        
        function selectBlocked(app)
            resetBrushButtons(app, app.Blocked_Button)
            app.Blocked_Button.Value = true;
            app.Blocked_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = 0;
        end
        
        function selectSwords(app)
            resetBrushButtons(app, app.Swords_Button)
            app.Swords_Button.Value = true;
            app.Swords_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = 3;
        end
        
        function selectBox(app)
            resetBrushButtons(app)
            app.Box_Button.Value = true;
            app.Box_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = 2;
        end
        
        function selectFox(app)
            resetBrushButtons(app, app.Fox_Button)
            app.Fox_Button.Value = true;
            app.Fox_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = 4;
        end
        
        function selectMiss(app)
            resetBrushButtons(app, app.Miss_Button)
            app.Miss_Button.Value = true;
            app.Miss_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = 1;
        end
        
        function selectErase(app)
            resetBrushButtons(app, app.Erase_Button)
            app.Erase_Button.Value = true;
            app.Erase_Label.Visible = true;
            app.lastBrush = app.brush;
            app.brush = NaN;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Blocked_Button.Value = true;
            recalculate(app);
        end

        % Value changed function: Blocked_Button
        function Blocked_ButtonValueChanged(app, event)
            selectBlocked(app);
            recalculate(app);
        end

        % Value changed function: Swords_Button
        function Swords_ButtonValueChanged(app, event)
            selectSwords(app);
            recalculate(app);
        end

        % Value changed function: Miss_Button
        function Miss_ButtonValueChanged(app, event)
            selectMiss(app);
            recalculate(app);
        end

        % Value changed function: Box_Button
        function Box_ButtonValueChanged(app, event)
            selectBox(app);
            recalculate(app);
        end

        % Value changed function: Fox_Button
        function Fox_ButtonValueChanged(app, event)
            selectFox(app);
            recalculate(app);
        end

        % Value changed function: Erase_Button
        function Erase_ButtonValueChanged(app, event)
            selectErase(app);
            recalculate(app);
        end

        % Button pushed function: Button, Button_10, Button_11, Button_12, 
        % ...and 32 other components
        function Grid_ButtonPushed(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.Source.Layout.Row-1, event.Source.Layout.Column) = app.brush;
            recalculate(app);
        end

        % Value changed function: Tell_Button
        function Tell_ButtonValueChanged(app, event)
            value = app.Tell_Button.Value;
            switch value
                case true
                    app.Tell_Button.Text = "Retell";
                case false
                    app.Tell_Button.Text = "Tell";
            end
        end

        % Button pushed function: Reset_Button
        function Reset_ButtonPushed(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern = nan(6);
            selectBlocked(app);
            recalculate(app);
        end

        % Value changed function: Gift_Button
        function Gift_ButtonValueChanged(app, event)
            value = app.Gift_Button.Value;
            switch value
                case true
                    app.Gift_Button.Text =["Coffer", "📦"];
                    app.Box_Button.Text = ["Coffer", "📦"];
                    app.Box_Button.BackgroundColor = [74 167 71]/255;
                    app.Box_Menu.Text = "Coffer 📦";
                case false
                    app.Gift_Button.Text= ["Gift", "🎁"];
                    app.Box_Button.Text = ["Gift", "🎁"];
                    app.Box_Button.BackgroundColor = [188 105 199]/255;
                    app.Box_Menu.Text = "Gift 🎁";
            end
            recalculate(app);
        end

        % Button pushed function: Undo_Button
        function Undo_ButtonPushed(app, event)
            temp = app.lastPattern;
            app.lastPattern = app.pattern;
            app.pattern = temp;
            temp = app.lastPatternBrush;
            app.lastBrush = app.brush;
            if isnan(temp)
                selectErase(app)
            else
                switch temp
                    case 0
                        selectBlocked(app)
                    case 1
                        selectMiss(app)
                    case 2
                        selectBox(app)
                    case 3
                        selectSwords(app)
                    case 4 
                        selectFox(app)
                end
            end
            recalculate(app);
        end

        % Menu selected function: Blocked_Menu
        function Blocked_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = 0;
            recalculate(app);
        end

        % Menu selected function: Swords_Menu
        function Swords_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = 3;
            recalculate(app);
        end

        % Menu selected function: Box_Menu
        function Box_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = 2;
            recalculate(app);
        end

        % Menu selected function: Fox_Menu
        function Fox_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = 4;
            recalculate(app);
        end

        % Menu selected function: Miss_Menu
        function Miss_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = 1;
            recalculate(app);
        end

        % Menu selected function: Erase_Menu
        function Erase_MenuSelected(app, event)
            app.lastPattern = app.pattern;
            app.lastPatternBrush = app.brush;
            app.pattern(event.ContextObject.Layout.Row-1, event.ContextObject.Layout.Column) = NaN;
            recalculate(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FauxHollowsSolverUIFigure = uifigure('Visible', 'off');
            app.FauxHollowsSolverUIFigure.Name = 'Faux Hollows Solver';
            app.GridLayout = uigridlayout(app.FauxHollowsSolverUIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.GridLayout, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.GridLayout, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.GridLayout, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.GridLayout, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.GridLayout, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.GridLayout, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.GridLayout, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.GridLayout, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.GridLayout, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.GridLayout, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.GridLayout, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.GridLayout, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.GridLayout, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.GridLayout, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.GridLayout, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.GridLayout, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.GridLayout, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.GridLayout, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Grid_ButtonPushed, true);
            app.Button_36.Text = '';
            app.Blocked_Button = uibutton(app.GridLayout, 'state');
            app.Blocked_Button.ValueChangedFcn = createCallbackFcn(app, @Blocked_ButtonValueChanged, true);
            app.Blocked_Button.Text = {'Blocked'; '🚫'};
            app.Swords_Button = uibutton(app.GridLayout, 'state');
            app.Swords_Button.ValueChangedFcn = createCallbackFcn(app, @Swords_ButtonValueChanged, true);
            app.Swords_Button.Text = {'Swords'; '⚔️'};
            app.Miss_Button = uibutton(app.GridLayout, 'state');
            app.Miss_Button.ValueChangedFcn = createCallbackFcn(app, @Miss_ButtonValueChanged, true);
            app.Miss_Button.Text = {'Miss'; '😐'};
            app.Box_Button = uibutton(app.GridLayout, 'state');
            app.Box_Button.ValueChangedFcn = createCallbackFcn(app, @Box_ButtonValueChanged, true);
            app.Box_Button.Text = {'Gift'; '🎁'};
            app.Fox_Button = uibutton(app.GridLayout, 'state');
            app.Fox_Button.ValueChangedFcn = createCallbackFcn(app, @Fox_ButtonValueChanged, true);
            app.Fox_Button.Text = {'Fox'; '🦊'};
            app.Erase_Button = uibutton(app.GridLayout, 'state');
            app.Erase_Button.ValueChangedFcn = createCallbackFcn(app, @Erase_ButtonValueChanged, true);
            app.Erase_Button.Text = {'Erase'; '❓'};
            app.Tell_Button = uibutton(app.GridLayout, 'state');
            app.Tell_Button.ValueChangedFcn = createCallbackFcn(app, @Tell_ButtonValueChanged, true);
            app.Tell_Button.Text = 'Tell';
            app.NameTextAreaLabel = uilabel(app.GridLayout);
            app.NameTextAreaLabel.Text = 'Name:';
            app.Name_TextArea = uitextarea(app.GridLayout);
            app.Name_TextArea.Editable = 'off';
            app.Reset_Button = uibutton(app.GridLayout, 'push');
            app.Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.Reset_Button.Text = 'Reset';
            app.Gift_Button = uibutton(app.GridLayout, 'state');
            app.Gift_Button.ValueChangedFcn = createCallbackFcn(app, @Gift_ButtonValueChanged, true);
            app.Gift_Button.Text = {'Gift'; '🎁'};
            app.Undo_Button = uibutton(app.GridLayout, 'push');
            app.Undo_Button.ButtonPushedFcn = createCallbackFcn(app, @Undo_ButtonPushed, true);
            app.Undo_Button.Text = 'Undo';
            app.Blocked_Label = uilabel(app.GridLayout);
            app.Blocked_Label.Text = 'Selected';
            app.Swords_Label = uilabel(app.GridLayout);
            app.Swords_Label.Visible = 'off';
            app.Swords_Label.Text = 'Selected';
            app.Box_Label = uilabel(app.GridLayout);
            app.Box_Label.Visible = 'off';
            app.Box_Label.Text = 'Selected';
            app.Fox_Label = uilabel(app.GridLayout);
            app.Fox_Label.Visible = 'off';
            app.Fox_Label.Text = 'Selected';
            app.Miss_Label = uilabel(app.GridLayout);
            app.Miss_Label.Visible = 'off';
            app.Miss_Label.Text = 'Selected';
            app.Erase_Label = uilabel(app.GridLayout);
            app.Erase_Label.Visible = 'off';
            app.Erase_Label.Text = 'Selected';
            app.Grid_ContextMenu = uicontextmenu(app.FauxHollowsSolverUIFigure);
            app.Blocked_Menu = uimenu(app.Grid_ContextMenu);
            app.Blocked_Menu.MenuSelectedFcn = createCallbackFcn(app, @Blocked_MenuSelected, true);
            app.Blocked_Menu.Text = 'Blocked 🚫 ';
            app.Swords_Menu = uimenu(app.Grid_ContextMenu);
            app.Swords_Menu.MenuSelectedFcn = createCallbackFcn(app, @Swords_MenuSelected, true);
            app.Swords_Menu.Text = 'Swords ⚔️';
            app.Box_Menu = uimenu(app.Grid_ContextMenu);
            app.Box_Menu.MenuSelectedFcn = createCallbackFcn(app, @Box_MenuSelected, true);
            app.Box_Menu.Text = 'Gift 🎁';
            app.Fox_Menu = uimenu(app.Grid_ContextMenu);
            app.Fox_Menu.MenuSelectedFcn = createCallbackFcn(app, @Fox_MenuSelected, true);
            app.Fox_Menu.Text = 'Fox 🦊';
            app.Miss_Menu = uimenu(app.Grid_ContextMenu);
            app.Miss_Menu.MenuSelectedFcn = createCallbackFcn(app, @Miss_MenuSelected, true);
            app.Miss_Menu.Text = 'Miss 😐';
            app.Erase_Menu = uimenu(app.Grid_ContextMenu);
            app.Erase_Menu.MenuSelectedFcn = createCallbackFcn(app, @Erase_MenuSelected, true);
            app.Erase_Menu.Text = 'Erase ❓';
            % Assign app.Grid_ContextMenu
            app.Button.ContextMenu = app.Grid_ContextMenu;
            app.Button_2.ContextMenu = app.Grid_ContextMenu;
            app.Button_3.ContextMenu = app.Grid_ContextMenu;
            app.Button_4.ContextMenu = app.Grid_ContextMenu;
            app.Button_5.ContextMenu = app.Grid_ContextMenu;
            app.Button_6.ContextMenu = app.Grid_ContextMenu;
            app.Button_7.ContextMenu = app.Grid_ContextMenu;
            app.Button_8.ContextMenu = app.Grid_ContextMenu;
            app.Button_9.ContextMenu = app.Grid_ContextMenu;
            app.Button_10.ContextMenu = app.Grid_ContextMenu;
            app.Button_11.ContextMenu = app.Grid_ContextMenu;
            app.Button_12.ContextMenu = app.Grid_ContextMenu;
            app.Button_13.ContextMenu = app.Grid_ContextMenu;
            app.Button_14.ContextMenu = app.Grid_ContextMenu;
            app.Button_15.ContextMenu = app.Grid_ContextMenu;
            app.Button_16.ContextMenu = app.Grid_ContextMenu;
            app.Button_17.ContextMenu = app.Grid_ContextMenu;
            app.Button_18.ContextMenu = app.Grid_ContextMenu;
            app.Button_19.ContextMenu = app.Grid_ContextMenu;
            app.Button_20.ContextMenu = app.Grid_ContextMenu;
            app.Button_21.ContextMenu = app.Grid_ContextMenu;
            app.Button_22.ContextMenu = app.Grid_ContextMenu;
            app.Button_23.ContextMenu = app.Grid_ContextMenu;
            app.Button_24.ContextMenu = app.Grid_ContextMenu;
            app.Button_25.ContextMenu = app.Grid_ContextMenu;
            app.Button_26.ContextMenu = app.Grid_ContextMenu;
            app.Button_27.ContextMenu = app.Grid_ContextMenu;
            app.Button_28.ContextMenu = app.Grid_ContextMenu;
            app.Button_29.ContextMenu = app.Grid_ContextMenu;
            app.Button_30.ContextMenu = app.Grid_ContextMenu;
            app.Button_31.ContextMenu = app.Grid_ContextMenu;
            app.Button_32.ContextMenu = app.Grid_ContextMenu;
            app.Button_33.ContextMenu = app.Grid_ContextMenu;
            app.Button_34.ContextMenu = app.Grid_ContextMenu;
            app.Button_35.ContextMenu = app.Grid_ContextMenu;
            app.Button_36.ContextMenu = app.Grid_ContextMenu;
            % Show the figure after all components are created
            app.FauxHollowsSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ffauxivsolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FauxHollowsSolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FauxHollowsSolverUIFigure)
        end
    end
end